classdef Mortgage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MortgageCalculatorUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        NmEditFieldLabel            matlab.ui.control.Label
        NmEditField                 matlab.ui.control.NumericEditField
        Don6hButton                 matlab.ui.control.Button
        MonthlyPaymentEditField     matlab.ui.control.NumericEditField
        TrmDropDownLabel            matlab.ui.control.Label
        TrmDropDown                 matlab.ui.control.DropDown
        TngySpinnerLabel            matlab.ui.control.Label
        TngySpinner                 matlab.ui.control.Spinner
        thngSpinnerLabel            matlab.ui.control.Label
        thngSpinner                 matlab.ui.control.Spinner
        nngySpinnerLabel            matlab.ui.control.Label
        nngySpinner                 matlab.ui.control.Spinner
        thngSpinner_2Label          matlab.ui.control.Label
        thngSpinner_2               matlab.ui.control.Spinner
        Don12hButton                matlab.ui.control.Button
        MonthlyPaymentEditField_2   matlab.ui.control.NumericEditField
        Don18hButton                matlab.ui.control.Button
        MonthlyPaymentEditField_3   matlab.ui.control.NumericEditField
        Don24hButton                matlab.ui.control.Button
        MonthlyPaymentEditField_4   matlab.ui.control.NumericEditField
        Don72hButton                matlab.ui.control.Button
        MonthlyPaymentEditField_5   matlab.ui.control.NumericEditField
        RightPanel                  matlab.ui.container.Panel
        PrincipalInterestUIAxes     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function results = inputX_6h(app,nam)
        
             if nam ==2016
                 nam =2017;
             end
             maxx = [5080,723,457,446,175.2];
             minn =[22,0,0,0,3.8];
             
             he_so=xlsread('nhap.xlsx','A3:E19');
             for (i=1:17)
                 if he_so(i,1)==nam
                      x01=xlsread('DataTrain.xlsx',char('E'+string(he_so(i,2))+':G'+string(he_so(i,3)-1)));
                      x02 =xlsread('DataTrain.xlsx',char('D'+string(he_so(i,2))+':D'+string(he_so(i,3)-1)));
                      x03=fillmissing(x02,'previous');
                      x06=xlsread('Htrieu.xlsx',char('B'+string(he_so(i,4)+1)+':B'+string(he_so(i,5))));
                     
                 end
             end
            
             x = [x03,x01,x06];
          
             for i = 1:5
                   x2(:,i) = (x(:,i)-minn(i))/(maxx(i)-minn(i));
             end
                
             results = x2;
            
        end
        
        function results = inputY_6h(app,nam)
             if nam ==2016
                 nam =2017;
             end
             
             he_so=xlsread('nhap.xlsx','A3:E19');
             for (i=1:17)
                 if he_so(i,1)==nam
                      y0=xlsread('DataTrain.xlsx',char('C'+string(he_so(i,2)+1)+':C'+string(he_so(i,3))));
                     
                     
                 end
             end
          
             y =fillmissing(y0,'previous');
             results = y;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MortgageCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {428, 428};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: Don6hButton
        function Don6hButtonPushed(app, event)
              
        
            nam = app.NmEditField.Value;
            ngay1 = app.TngySpinner.Value;
            thang1 = app.thngSpinner.Value;
            ngay2 = app.nngySpinner.Value;
            thang2 = app.thngSpinner_2.Value;
            
            
            evalin('base','train');            
          
            net = evalin('base','net');


            x2=inputX_6h(app,nam);
            y=inputY_6h(app,nam);


            y1 = exp(net(x2'))-78;
            
            dem = 0;
            for i=1:length(y)
              if abs(y(i)-y1(i))< 24
                dem =dem + 1;
              end
            end
            H = dem/ length(y);
          

            app.MonthlyPaymentEditField.Value = H;
            
            f = fopen('output.txt','w');
            fprintf(f,'%d \n',int32(y1));
            fclose(f);

            plot(app.PrincipalInterestUIAxes,y);
            hold (app.PrincipalInterestUIAxes,'on');
            plot(app.PrincipalInterestUIAxes,y1);
            legend(app.PrincipalInterestUIAxes,'original values','predicted values');
            hold (app.PrincipalInterestUIAxes,'off');
            
           
        end

        % Value changed function: TngySpinner
        function TngySpinnerValueChanged(app, event)
            value = app.TngySpinner.Value;
            
        end

        % Value changed function: thngSpinner
        function thngSpinnerValueChanged(app, event)
            value = app.thngSpinner.Value;
            
        end

        % Value changed function: nngySpinner
        function nngySpinnerValueChanged(app, event)
            value = app.nngySpinner.Value;
            
        end

        % Value changed function: thngSpinner_2
        function thngSpinner_2ValueChanged(app, event)
            value = app.thngSpinner_2.Value;
            
        end

        % Button pushed function: Don12hButton
        function Don12hButtonPushed(app, event)
            
        
            nam = app.NmEditField.Value;
            ngay1 = app.TngySpinner.Value;
            thang1 = app.thngSpinner.Value;
            ngay2 = app.nngySpinner.Value;
            thang2 = app.thngSpinner_2.Value;
            
            
            evalin('base','train');        
            net1 = evalin('base','net1');
            
            
            maxx = [5080,723,457,446,175.2];%,6029];
            minn =[22,0,0,0,3.8];
          
        
            if (ngay1==1) && (thang1==10) &&(ngay2==7) &&(thang2 ==10) && (nam == 2006)
                x01=xlsread('Data2006.xlsx','E246:G272');
                x02 =xlsread('Data2006.xlsx','D246:D272');
               
                %x02=xlsread('mua2006.xlsx','D2:G157');
                x03=fillmissing(x02,'previous');
               
               
                x06=xlsread('Htrieu2.xlsx','B2687:B2713');
                %x06 =xlsread('mua2006.xlsx','H14:H169');
                
                x = [x03,x01,x06];
                %x = [x03,x06];
                y0=xlsread('Data2006.xlsx','C247:C273');
                %y0=xlsread('mua2006.xlsx','C14:C169');
                y =fillmissing(y0,'previous');
                for i = 1:5
                   %xt2(:,i) = (x(:,i)-minn(i))/(maxx(i)-minn(i));
                   x2(:,i) = (x(:,i)-minn(i))/(maxx(i)-minn(i));
                end
                x07=x2(:,5);
                x2(27,:)=[];
                
                x2(:,5)=[];
                x07(1)=[];                
                xt2=[x2,x07];
                y(1,:)=[];
                
            elseif  (ngay1==1) && (thang1==8) &&(ngay2==14) &&(thang2 ==10) && (nam == 2006)  
                x01=xlsread('Data2006.xlsx','E2:G299');
                x02=xlsread('Data2006.xlsx','D2:D299');
                x03=fillmissing(x02,'previous');
                x06=xlsread('Htrieu2.xlsx','B2442:B2739');
                x=[x03,x01,x06];
                y0=xlsread('Data2006.xlsx','C4:C301');
                y=fillmissing(y0,'previous');
                for i = 1:5
                    xt2(:,i) = (x(:,i)-minn(i))/(maxx(i)-minn(i));
                end
            else
                x2=inputX_6h(app,nam);
                y=inputY_6h(app,nam);
                x07=x2(:,5);
                if nam ==2018 
                    x2(431,:)=[];
                else 
                    x2(487,:)=[];
                end
             
                
                x2(:,5)=[];
                x07(1)=[];                
                xt2=[x2,x07];
                y(1,:)=[];
            end


            y1 = exp(net1(xt2'))-78;
            
            dem = 0;
            for i=1:length(y)
              if abs(y(i)-y1(i))< 45
                dem =dem + 1;
              end
            end
            H = dem/ length(y);
          

            app.MonthlyPaymentEditField_2.Value = H;
            
            f = fopen('output.txt','w');
            fprintf(f,'%d \n',int32(y1));
            fclose(f);

            plot(app.PrincipalInterestUIAxes,y);
            hold (app.PrincipalInterestUIAxes,'on');
            plot(app.PrincipalInterestUIAxes,y1);
            legend(app.PrincipalInterestUIAxes,'original values','predicted values');
            hold (app.PrincipalInterestUIAxes,'off');
        end

        % Value changed function: NmEditField
        function NmEditFieldValueChanged(app, event)
            value = app.NmEditField.Value;
            
        end

        % Button pushed function: Don18hButton
        function Don18hButtonPushed(app, event)
            nam = app.NmEditField.Value;
            ngay1 = app.TngySpinner.Value;
            thang1 = app.thngSpinner.Value;
            ngay2 = app.nngySpinner.Value;
            thang2 = app.thngSpinner_2.Value;
            
            
            evalin('base','train');            
          
            net = evalin('base','net2');


            x2=inputX_6h(app,nam);
            y=inputY_6h(app,nam);
                
            x07=x2(:,5);
            
            if nam == 2018 
                    x2(430:431,:)=[];
            else 
                    x2(486:487,:)=[];
            end
                
            x2(:,5)=[];
            x07(1:2)=[];                
            xt2=[x2,x07];
            y(1:2,:)=[];     


            y1 = exp(net(xt2'))-78;
            
            dem = 0;
            for i=1:length(y)
              if abs(y(i)-y1(i))< 50
                dem =dem + 1;
              end
            end
            H = dem/ length(y);
          

            app.MonthlyPaymentEditField_3.Value = H;
            
            f = fopen('output.txt','w');
            fprintf(f,'%d \n',int32(y1));
            fclose(f);

            plot(app.PrincipalInterestUIAxes,y);
            hold (app.PrincipalInterestUIAxes,'on');
            plot(app.PrincipalInterestUIAxes,y1);
            legend(app.PrincipalInterestUIAxes,'original values','predicted values');
            hold (app.PrincipalInterestUIAxes,'off');    
        end

        % Button pushed function: Don24hButton
        function Don24hButtonPushed(app, event)
            nam = app.NmEditField.Value;
            ngay1 = app.TngySpinner.Value;
            thang1 = app.thngSpinner.Value;
            ngay2 = app.nngySpinner.Value;
            thang2 = app.thngSpinner_2.Value;
            
            
            evalin('base','train');            
          
            net = evalin('base','net3');


            x2=inputX_6h(app,nam);
            y=inputY_6h(app,nam);
            
            x07=x2(:,5);
          
            if nam ==2018 
                    x2(429:431,:)=[];
            else 
                    x2(485:487,:)=[];
            end
                
            x2(:,5)=[];
            x07(1:3)=[];                
            xt2=[x2,x07];
            y(1:3,:)=[];    


            y1 = exp(net(xt2'))-78;
            
            dem = 0;
            for i=1:length(y)
              if abs(y(i)-y1(i))< 53
                dem =dem + 1;
              end
            end
            H = dem/ length(y);
          

            app.MonthlyPaymentEditField_4.Value = H;
            f = fopen('output.txt','w');
            fprintf(f,'%d \n',int32(y1));
            fclose(f);

            plot(app.PrincipalInterestUIAxes,y);
            hold (app.PrincipalInterestUIAxes,'on');
            plot(app.PrincipalInterestUIAxes,y1);
            legend(app.PrincipalInterestUIAxes,'original values','predicted values');
            hold (app.PrincipalInterestUIAxes,'off');    
        end

        % Button pushed function: Don72hButton
        function Don72hButtonPushed(app, event)
            nam = app.NmEditField.Value;
            ngay1 = app.TngySpinner.Value;
            thang1 = app.thngSpinner.Value;
            ngay2 = app.nngySpinner.Value;
            thang2 = app.thngSpinner_2.Value;
            
            
            evalin('base','train');            
          
            net = evalin('base','net4');


            x2=inputX_6h(app,nam);
            y=inputY_6h(app,nam);
            
            x07=x2(:,5);
      
            if nam ==2018 
                    x2(421:431,:)=[];
            else 
                    x2(477:487,:)=[];
            end
                
            x2(:,5)=[];
            x07(1:11)=[];                
            xt2=[x2,x07];
            y(1:11,:)=[];    


            y1 = exp(net(xt2'))-78;
            
            dem = 0;
            for i=1:length(y)
              if abs(y(i)-y1(i))< 60
                dem =dem + 1;
              end
            end
            H = dem/ length(y);
          

            app.MonthlyPaymentEditField_5.Value = H;
            

            plot(app.PrincipalInterestUIAxes,y);
            hold (app.PrincipalInterestUIAxes,'on');
            plot(app.PrincipalInterestUIAxes,y1);
            legend(app.PrincipalInterestUIAxes,'original values','predicted values');
            hold (app.PrincipalInterestUIAxes,'off');       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MortgageCalculatorUIFigure = uifigure('Visible', 'off');
            app.MortgageCalculatorUIFigure.Name = 'Mortgage Calculator';
            app.MortgageCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MortgageCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.NmEditFieldLabel = uilabel(app.LeftPanel);
            app.NmEditFieldLabel.Text = 'Năm';
            app.NmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NmEditField.Limits = [2000 2100];
            app.NmEditField.ValueDisplayFormat = '%.0f';
            app.NmEditField.ValueChangedFcn = createCallbackFcn(app, @NmEditFieldValueChanged, true);
            app.NmEditField.Value = 2006;
            app.Don6hButton = uibutton(app.LeftPanel, 'push');
            app.Don6hButton.ButtonPushedFcn = createCallbackFcn(app, @Don6hButtonPushed, true);
            app.Don6hButton.Text = 'Dự đoán 6h';
            app.MonthlyPaymentEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MonthlyPaymentEditField.ValueDisplayFormat = '%7.2f';
            app.MonthlyPaymentEditField.Editable = 'off';
            app.TrmDropDownLabel = uilabel(app.LeftPanel);
            app.TrmDropDownLabel.Text = 'Trạm';
            app.TrmDropDown = uidropdown(app.LeftPanel);
            app.TrmDropDown.Items = {'Mai Hóa', 'Đông Tâm', 'Nam', 'Bắc'};
            app.TrmDropDown.Value = 'Mai Hóa';
            app.TngySpinnerLabel = uilabel(app.LeftPanel);
            app.TngySpinnerLabel.Text = 'Từ ngày';
            app.TngySpinner = uispinner(app.LeftPanel);
            app.TngySpinner.Limits = [1 31];
            app.TngySpinner.ValueChangedFcn = createCallbackFcn(app, @TngySpinnerValueChanged, true);
            app.TngySpinner.Value = 1;
            app.thngSpinnerLabel = uilabel(app.LeftPanel);
            app.thngSpinnerLabel.Text = 'tháng';
            app.thngSpinner = uispinner(app.LeftPanel);
            app.thngSpinner.Limits = [8 11];
            app.thngSpinner.ValueChangedFcn = createCallbackFcn(app, @thngSpinnerValueChanged, true);
            app.thngSpinner.Value = 8;
            app.nngySpinnerLabel = uilabel(app.LeftPanel);
            app.nngySpinnerLabel.Text = 'đến ngày';
            app.nngySpinner = uispinner(app.LeftPanel);
            app.nngySpinner.Limits = [1 31];
            app.nngySpinner.ValueChangedFcn = createCallbackFcn(app, @nngySpinnerValueChanged, true);
            app.nngySpinner.Value = 30;
            app.thngSpinner_2Label = uilabel(app.LeftPanel);
            app.thngSpinner_2Label.Text = 'tháng';
            app.thngSpinner_2 = uispinner(app.LeftPanel);
            app.thngSpinner_2.Limits = [8 11];
            app.thngSpinner_2.ValueChangedFcn = createCallbackFcn(app, @thngSpinner_2ValueChanged, true);
            app.thngSpinner_2.Value = 11;
            app.Don12hButton = uibutton(app.LeftPanel, 'push');
            app.Don12hButton.ButtonPushedFcn = createCallbackFcn(app, @Don12hButtonPushed, true);
            app.Don12hButton.Text = {'Dự đoán 12h'; ''};
            app.MonthlyPaymentEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.MonthlyPaymentEditField_2.ValueDisplayFormat = '%7.2f';
            app.MonthlyPaymentEditField_2.Editable = 'off';
            app.Don18hButton = uibutton(app.LeftPanel, 'push');
            app.Don18hButton.ButtonPushedFcn = createCallbackFcn(app, @Don18hButtonPushed, true);
            app.Don18hButton.Text = 'Dự đoán 18h';
            app.MonthlyPaymentEditField_3 = uieditfield(app.LeftPanel, 'numeric');
            app.MonthlyPaymentEditField_3.ValueDisplayFormat = '%7.2f';
            app.MonthlyPaymentEditField_3.Editable = 'off';
            app.Don24hButton = uibutton(app.LeftPanel, 'push');
            app.Don24hButton.ButtonPushedFcn = createCallbackFcn(app, @Don24hButtonPushed, true);
            app.Don24hButton.Text = 'Dự đoán 24h';
            app.MonthlyPaymentEditField_4 = uieditfield(app.LeftPanel, 'numeric');
            app.MonthlyPaymentEditField_4.ValueDisplayFormat = '%7.2f';
            app.MonthlyPaymentEditField_4.Editable = 'off';
            app.Don72hButton = uibutton(app.LeftPanel, 'push');
            app.Don72hButton.ButtonPushedFcn = createCallbackFcn(app, @Don72hButtonPushed, true);
            app.Don72hButton.Text = 'Dự đoán 72h';
            app.MonthlyPaymentEditField_5 = uieditfield(app.LeftPanel, 'numeric');
            app.MonthlyPaymentEditField_5.ValueDisplayFormat = '%7.2f';
            app.MonthlyPaymentEditField_5.Editable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.PrincipalInterestUIAxes = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes, 'Biểu đồ tương quan')
            xlabel(app.PrincipalInterestUIAxes, 'Thời gian')
            ylabel(app.PrincipalInterestUIAxes, 'Hmax Mai Hóa')
            % Show the figure after all components are created
            app.MortgageCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mortgage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MortgageCalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MortgageCalculatorUIFigure)
        end
    end
end